classdef Calculos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AyudaLabel                    matlab.ui.control.Label
        CostosBtn                     matlab.ui.control.Button
        Ayuda                         matlab.ui.control.Button
        TopologiaBtn                  matlab.ui.control.Button
        ResultadosPanel_2             matlab.ui.container.Panel
        Paneles                       matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ImediaLbl                     matlab.ui.control.Label
        TmediaLbl                     matlab.ui.control.Label
        CorrientemediaporpanelALabel  matlab.ui.control.Label
        TensionmediaporpanelVLabel    matlab.ui.control.Label
        VCorrienteLbl                 matlab.ui.control.Label
        VTensionLbl                   matlab.ui.control.Label
        VPotenciaLbl                  matlab.ui.control.Label
        TemCeldLbl                    matlab.ui.control.Label
        VariacindeCorrienteLabel      matlab.ui.control.Label
        VariacindeTensionLabel        matlab.ui.control.Label
        VariacindePotenciaLabel       matlab.ui.control.Label
        TemperaturaCeldasCLabel       matlab.ui.control.Label
        NumPaneleslbl                 matlab.ui.control.Label
        NmerodePanelesLabel           matlab.ui.control.Label
        BateriasPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NumBaterias                   matlab.ui.control.Label
        NmerodeBateriasLabel          matlab.ui.control.Label
        ICorrBatt                     matlab.ui.control.Label
        INomBatt                      matlab.ui.control.Label
        CapacidadCorregidaAhLabel     matlab.ui.control.Label
        CapacidadNominalAhLabel       matlab.ui.control.Label
        BateriaPanel                  matlab.ui.container.Panel
        ProfundidadBatLabel_2         matlab.ui.control.Label
        CapacidadBateria              matlab.ui.control.NumericEditField
        TipoBateria                   matlab.ui.control.DropDown
        TipoBateriaLabel              matlab.ui.control.Label
        ProfundidadBatLabel           matlab.ui.control.Label
        DiasdeAutonomiaLabel          matlab.ui.control.Label
        ProfunDescarga                matlab.ui.control.NumericEditField
        DiasAuto                      matlab.ui.control.NumericEditField
        InversorPanel                 matlab.ui.container.Panel
        TensionLabel                  matlab.ui.control.Label
        EficienciaLabel               matlab.ui.control.Label
        MaxCorrienteLabel             matlab.ui.control.Label
        PotenciaNominalLabel          matlab.ui.control.Label
        TipoInversorLabel             matlab.ui.control.Label
        TensionNomInv                 matlab.ui.control.NumericEditField
        EficienciaInv                 matlab.ui.control.NumericEditField
        MCorrienteInv                 matlab.ui.control.NumericEditField
        PNominalInv                   matlab.ui.control.NumericEditField
        TipoInv                       matlab.ui.control.DropDown
        CalcularButton                matlab.ui.control.Button
        SeleccindeEquiposLabel        matlab.ui.control.Label
        TemperaturaAmbientePanel      matlab.ui.container.Panel
        TempLbl                       matlab.ui.control.NumericEditField
        TempCEditFieldLabel           matlab.ui.control.Label
        CSlider                       matlab.ui.control.Slider
        CSliderLabel                  matlab.ui.control.Label
        PanelSolar                    matlab.ui.container.Panel
        TipodePanelLabel              matlab.ui.control.Label
        CoeficientedeCorrienteLabel   matlab.ui.control.Label
        CoeficientePotenciaLabel      matlab.ui.control.Label
        CoeficientedeTensionLabel     matlab.ui.control.Label
        PotenciaSalidaLabel           matlab.ui.control.Label
        PNOCTC                        matlab.ui.control.NumericEditField
        NOCTCEditFieldLabel           matlab.ui.control.Label
        PCC                           matlab.ui.control.NumericEditField
        PCT                           matlab.ui.control.NumericEditField
        PIm                           matlab.ui.control.NumericEditField
        ImAEditFieldLabel             matlab.ui.control.Label
        PIsc                          matlab.ui.control.NumericEditField
        IscAEditFieldLabel            matlab.ui.control.Label
        PVm                           matlab.ui.control.NumericEditField
        VmVEditFieldLabel             matlab.ui.control.Label
        PVoc                          matlab.ui.control.NumericEditField
        VocVEditFieldLabel            matlab.ui.control.Label
        PCP                           matlab.ui.control.NumericEditField
        PPSalida                      matlab.ui.control.NumericEditField
        TipoPanel                     matlab.ui.control.DropDown
        Image                         matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
    end

    properties (Access = public)
        Menoapp;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Idioma)
            global RadiacionPromedioGlobal Idioma1;
            global Energiai;
            global Potenciai;
            %app.Label2_2.Text = ['Energia (Wh): ', num2str(Energiai)];
            %app.Label2_3.Text = ['Potencia (W): ', num2str(Potenciai)];
            %app.Label.Text = ['Radiación Promedio: ', num2str(RadiacionPromedioGlobal)];
            %app.Label.Text=Idioma;
            Idioma1=Idioma
            if strcmp(Idioma,'English')
            app.SeleccindeEquiposLabel.Text='Equipment';
            app.PanelSolar.Title='Solar Panel';
            app.InversorPanel.Title='Investor';
            app.TemperaturaAmbientePanel.Title='Room Temperature';
            app.BateriaPanel.Title='Battery';
            app.CalcularButton.Text='Calculate';
            app.TopologiaBtn.Text='Topology';
            app.ResultadosPanel_2.Title='Results';
            app.Paneles.Title='Solar Panel'; 
            app.BateriasPanel.Title='Battery';
            app.PotenciaSalidaLabel.Text='Power Output';
            app.CoeficientedeCorrienteLabel.Text='Current Coefficient';
            app.CoeficientePotenciaLabel.Text='Power Coefficient';
            app.CoeficientedeTensionLabel.Text='Voltage Coefficient';
            app.TipoInversorLabel.Text='Inverter Type';
            app.PotenciaNominalLabel.Text='Rated Power at 25°C';
            app.MaxCorrienteLabel.Text='Maximun Supported Current [A]';
            app.EficienciaLabel.Text='Efficiency [%]';
            app.TensionLabel.Text='Nominal Voltage';
            app.DiasdeAutonomiaLabel.Text='Days of Autonomy';
            app.ProfundidadBatLabel.Text='Discharge Depth';
            app.NmerodePanelesLabel.Text='Number of Panels';
            app.TemperaturaCeldasCLabel.Text='Cell Temperature [°C]';
            app.VariacindePotenciaLabel.Text='Power Variation [%]';
            app.VariacindeTensionLabel.Text='Voltage Variation [%]';
            app.VariacindeCorrienteLabel.Text='Current Variation [%]';
            app.TensionmediaporpanelVLabel.Text='Average Voltage panel [V]';
            app.CorrientemediaporpanelALabel.Text='Average Current Panel [A]';
            app.CapacidadCorregidaAhLabel.Text='Rated Capacity [Ah]';
            app.CapacidadNominalAhLabel.Text='Corrected Capacity [Ah]';
            app.TipoBateriaLabel.Text='Battery Type';
            app.NmerodeBateriasLabel.Text='Number of batteries';
            app.ProfundidadBatLabel_2.Text='Discharge capacity';
            app.TipodePanelLabel.Text='Panel Type';
            else
            end
           
        end

        % Callback function
        function CargasButtonPushed(app, event)
            Configuracion
            delete(Calculos)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            global Energiai;
            global Temp NbSerie NbParalelo Imedia Vmedia NumPaneles NumBaterias;
            global RadiacionPromedioGlobal;
            global Potenciai;
            valorEficienciaInvPorcentaje = app.EficienciaInv.Value;
            valorEficienciaInvDecimal = valorEficienciaInvPorcentaje / 100;
            resultado = Energiai / valorEficienciaInvDecimal;
            Energiatotalsuministrada=resultado*1.18;
            resultado1 = Potenciai/ valorEficienciaInvDecimal;
            PTotal1= Energiatotalsuministrada/str2double(RadiacionPromedioGlobal);
            ETotal1=Energiatotalsuministrada;
            Nump=app.PPSalida.Value;
            Noct=app.PNOCTC.Value;
            NumPaneles=round(PTotal1/ Nump);
            TempCelda=Temp+((Noct-20)/800)*1000;
            VarTemp=TempCelda-25;
            VarPotencia=app.PCP.Value*VarTemp;
            VarTension=app.PCT.Value*VarTemp;
            VarCorriente=app.PCC.Value*VarTemp;
            ICarga=ETotal1/app.TensionNomInv.Value;
            INom=ICarga*app.DiasAuto.Value;
            ICorre=INom/(app.ProfunDescarga.Value/100);
            app.VCorrienteLbl.Text=num2str(VarCorriente);
            app.VTensionLbl.Text=num2str(VarTension);
            app.VPotenciaLbl.Text=num2str(VarPotencia);
            app.TemCeldLbl.Text=num2str(TempCelda);
            app.NumPaneleslbl.Text=num2str(NumPaneles);
            app.INomBatt.Text=num2str(INom);
            app.ICorrBatt.Text=num2str(ICorre);
            Vmedia=app.PVm.Value*(1+VarTension/100);
            Imedia=app.PIm.Value*(1+VarCorriente/100);
            app.TmediaLbl.Text=num2str(Vmedia);
            app.ImediaLbl.Text=num2str(Imedia);
            app.TopologiaBtn.Enable="on";
            if (app.TensionNomInv.Value==24)
                NumBaterias=2*round(ICorre/app.CapacidadBateria.Value);
                NbSerie=2;
                NbParalelo=NumBaterias/2;
            elseif (app.TensionNomInv.Value==48)
                NumBaterias=4*round(ICorre/app.CapacidadBateria.Value); 
                NbSerie=4;
                NbParalelo=NumBaterias/4;
            else
                NumBaterias=round(ICorre/app.CapacidadBateria.Value);
                NbSerie=1;
                NbParalelo=NumBaterias;
            end
            app.NumBaterias.Text=num2str(NumBaterias);
            
     
        end

        % Value changed function: TipoPanel
        function TipoPanelValueChanged(app, event)
            global ValorPanel;
            value = app.TipoPanel.Value;
            switch value
                case '1'
                    app.PPSalida.Value=100;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.32;
                    app.PIm.Value=4.93;
                    app.PIsc.Value=6.04;
                    app.PVm.Value=20.37;
                    app.PVoc.Value=21.58;
                    app.PCP.Value=-0.39;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=234000;
                case '2'
                    app.PPSalida.Value=150;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.32;
                    app.PIm.Value=6.98;
                    app.PIsc.Value=8.73;
                    app.PVm.Value=21;
                    app.PVoc.Value=22.59;
                    app.PCP.Value=-0.39;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=387900;
                case '3'
                    app.PPSalida.Value=180;
                    app.PNOCTC.Value=47;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.32;
                    app.PIm.Value=7.51;
                    app.PIsc.Value=8.23;
                    app.PVm.Value=24.12;
                    app.PVoc.Value=25.59;
                    app.PCP.Value=-0.39;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=393000;
                case '4'
                    app.PPSalida.Value=260;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.04;
                    app.PCT.Value=-0.32;
                    app.PIm.Value=8.51;
                    app.PIsc.Value=9.11;
                    app.PVm.Value=30.56;
                    app.PVoc.Value=36.7;
                    app.PCP.Value=-0.4;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=571000;
                case '5'
                    app.PPSalida.Value=230;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.32;
                    app.PIm.Value=7.73;
                    app.PIsc.Value=8.22;
                    app.PVm.Value=29.8;
                    app.PVoc.Value=37;
                    app.PCP.Value=-0.45;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=560000;
                case '6'
                    app.PPSalida.Value=275;
                    app.PNOCTC.Value=44.4;
                    app.PCC.Value=0.04;
                    app.PCT.Value=-0.34;
                    app.PIm.Value=8.61;
                    app.PIsc.Value=9.15;
                    app.PVm.Value=32;
                    app.PVoc.Value=39.1;
                    app.PCP.Value=-0.39;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=580000;
                case '7'
                    app.PPSalida.Value=350;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.06;
                    app.PCT.Value=-0.31;
                    app.PIm.Value=8.99;
                    app.PIsc.Value=9.38;
                    app.PVm.Value=38.93;
                    app.PVoc.Value=47.12;
                    app.PCP.Value=-0.39;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=615000;
                case '8'
                    app.PPSalida.Value=455;
                    app.PNOCTC.Value=41;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.30;
                    app.PIm.Value=8.80;
                    app.PIsc.Value=9.48;
                    app.PVm.Value=38.49;
                    app.PVoc.Value=45.82;
                    app.PCP.Value=-0.35;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=660000;
                 case '9'
                    app.PPSalida.Value=550;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.048;
                    app.PCT.Value=-0.28;
                    app.PIm.Value=10.66;
                    app.PIsc.Value=11.39;
                    app.PVm.Value=38.42;
                    app.PVoc.Value=46.07;
                    app.PCP.Value=-0.35;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=851000;
                case '10'
                    app.PPSalida.Value=150;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.06;
                    app.PCT.Value=-0.31;
                    app.PIm.Value=8.15;
                    app.PIsc.Value=8.75;
                    app.PVm.Value=18.4;
                    app.PVoc.Value=22.51;
                    app.PCP.Value=-0.41;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=340000;
                case '11'
                    app.PPSalida.Value=50;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.05;
                    app.PCT.Value=-0.31;
                    app.PIm.Value=2.81;
                    app.PIsc.Value=3.03;
                    app.PVm.Value=17.8;
                    app.PVoc.Value=22.3;
                    app.PCP.Value=-0.41;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=220000;
                case '12'
                    app.PPSalida.Value=200;
                    app.PNOCTC.Value=45;
                    app.PCC.Value=0.06;
                    app.PCT.Value=-0.31;
                    app.PIm.Value=10.79;
                    app.PIsc.Value=11.75;
                    app.PVm.Value=18.54;
                    app.PVoc.Value=22.51;
                    app.PCP.Value=-0.396;
                    app.PPSalida.Enable="off";
                    app.PNOCTC.Enable="off";
                    app.PCC.Enable="off";
                    app.PCT.Enable="off";
                    app.PIm.Enable="off";
                    app.PIsc.Enable="off";
                    app.PVm.Enable="off";
                    app.PVoc.Enable="off";
                    app.PCP.Enable="off";
                    ValorPanel=457000;
                case '13'
                    app.PPSalida.Enable="on";
                    app.PNOCTC.Enable="on";
                    app.PCC.Enable="on";
                    app.PCT.Enable="on";
                    app.PIm.Enable="on";
                    app.PIsc.Enable="on";
                    app.PVm.Enable="on";
                    app.PVoc.Enable="on";
                    app.PCP.Enable="on";
            end
        end

        % Value changed function: CSlider
        function CSliderValueChanged(app, event)
            global Temp
            value = app.CSlider.Value;
            Temp=value;
            app.TempLbl.Value=value;
        end

        % Drop down opening function: TipoInv
        function TipoInvDropDownOpening(app, event)

        end

        % Value changed function: TipoInv
        function TipoInvValueChanged(app, event)
            global ValorInv;
            value = app.TipoInv.Value;
            switch value
                case '1'
                    app.TensionNomInv.Value=48;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=100;
                    app.PNominalInv.Value=4000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=10969456;
                case '2'
                    app.TensionNomInv.Value=48;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=80;
                    app.PNominalInv.Value=4000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=3242000;
                case '3'
                    app.TensionNomInv.Value=48;
                    app.EficienciaInv.Value=93;
                    app.MCorrienteInv.Value=60;
                    app.PNominalInv.Value=3500;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=3516000;
                case '4'
                    app.TensionNomInv.Value=12;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=25;
                    app.PNominalInv.Value=600;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=2394000;
                case '5'
                    app.TensionNomInv.Value=24;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=30;
                    app.PNominalInv.Value=1200;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=1720000;
                case '6'
                    app.TensionNomInv.Value=24;
                    app.EficienciaInv.Value=93;
                    app.MCorrienteInv.Value=80;
                    app.PNominalInv.Value=2000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=2230000;
                case '7'
                    app.TensionNomInv.Value=48;
                    app.EficienciaInv.Value=93;
                    app.MCorrienteInv.Value=100;
                    app.PNominalInv.Value=5000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=3390000;
                case '8'
                    app.TensionNomInv.Value=12;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=40;
                    app.PNominalInv.Value=3000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=2495000;
                case '9'
                    app.TensionNomInv.Value=12;
                    app.EficienciaInv.Value=95;
                    app.MCorrienteInv.Value=40;
                    app.PNominalInv.Value=1000;
                    app.TensionNomInv.Enable="off";
                    app.EficienciaInv.Enable="off";
                    app.MCorrienteInv.Enable="off";
                    app.PNominalInv.Enable="off";
                    ValorInv=1668500;
                case '10'
                    app.TensionNomInv.Enable="on";
                    app.EficienciaInv.Enable="on";
                    app.MCorrienteInv.Enable="on";
                    app.PNominalInv.Enable="on";
                    ValorInv=0;
            end            
        end

        % Button pushed function: TopologiaBtn
        function TopologiaBtnButtonPushed(app, event)
            global Idioma1 NbSerie NbParalelo Imedia Vmedia NumPaneles; 
            VBat=app.TensionNomInv.Value;
            IBat=NbParalelo*app.CapacidadBateria.Value;
            IInversor=app.MCorrienteInv.Value;
            PInversor=app.PNominalInv.Value;
            Topologia(Idioma1,NbSerie,NbParalelo,VBat,IBat,IInversor,PInversor,Imedia,Vmedia,NumPaneles);
            app.CostosBtn.Enable="on";
            %app.TopologiaBtn.Enable="on";
        end

        % Value changed function: TipoBateria
        function TipoBateriaValueChanged(app, event)
            global ValorBat;
            value = app.TipoBateria.Value;
            switch value
                case '1'
                  app.ProfunDescarga.Value=60;
                  app.CapacidadBateria.Value=200;
                  ValorBat=1357000;
                case '2'
                  app.ProfunDescarga.Value=60;
                  app.CapacidadBateria.Value=150;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=1100000;
                case '3'
                  app.ProfunDescarga.Value=60;
                  app.CapacidadBateria.Value=100;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=725000;
                case '4'
                  app.ProfunDescarga.Value=55;
                  app.CapacidadBateria.Value=200;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=1200000;
                case '5'
                  app.ProfunDescarga.Value=80;
                  app.CapacidadBateria.Value=200;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=4400000;
                case '6'
                  app.ProfunDescarga.Value=80;
                  app.CapacidadBateria.Value=100;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=2850000;
                case '7'
                  app.ProfunDescarga.Value=80;
                  app.CapacidadBateria.Value=50;
                  app.ProfunDescarga.Enable="off";
                  app.CapacidadBateria.Enable="off";
                  ValorBat=1010777;
                case '8'
                  app.ProfunDescarga.Enable="on";
                  app.CapacidadBateria.Enable="on";


            end
            

        end

        % Button pushed function: Ayuda
        function AyudaButtonPushed(app, event)
            msgbox({'En esta ventana se puede seleccionar los equipos a usar en el sistema fotovoltaico';''; ...
                'El software cuenta con diferentes tipos de paneles, inversores y baterias precargados'; ...
                'Los cuales son necesarios para el sistema fotovoltaico.';'';...
                'Cada elemento puede ser elegido y modificado segun necesidades del usuario.'; ...
                'Una vez seleccionado los tres equipos y la temperatura ambiente del lugar se procede a Calcular'});

        end

        % Button pushed function: CostosBtn
        function CostosBtnButtonPushed(app, event)
            global Idioma1 ValorPanel ValorBat ValorInv NumPaneles NumBaterias;
            Presupuesto(Idioma1,ValorPanel,ValorBat,ValorInv,NumPaneles,NumBaterias);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Barra.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Barra.png');
            app.PanelSolar = uipanel(app.UIFigure);
            app.PanelSolar.Title = 'Panel Solar';
            app.TipoPanel = uidropdown(app.PanelSolar);
            app.TipoPanel.Items = {'ESTARSOLAR RTM-100P 24V', 'ESTARSOLAR RT6E-150-170P 24V', 'OSDA 180W MONOCRISTALINO 24V-48 CELULAS ', 'POWEST 260W 24V POLI-CRISTALINO', 'LUXOR Eco line 60/230 W Policristalino', 'ECO GREEN ENERGY 275W POLICRISTALINO24 VDC', 'ECO GREEN ENERGY 350W 24 vdc MONOCRISTALINO', 'EOS POLY455 M6 24V', 'EOS POLY 550 M10 24V', 'POLYCRYSTALLINE MODULE 150W-12V', 'MESM-50W-12V', 'EOS POLY 200W-12V', 'Otro'};
            app.TipoPanel.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.TipoPanel.ValueChangedFcn = createCallbackFcn(app, @TipoPanelValueChanged, true);
            app.TipoPanel.Value = '13';
            app.PPSalida = uieditfield(app.PanelSolar, 'numeric');
            app.PCP = uieditfield(app.PanelSolar, 'numeric');
            app.PCP.ValueDisplayFormat = '%.2f';
            app.VocVEditFieldLabel = uilabel(app.PanelSolar);
            app.VocVEditFieldLabel.Text = 'Voc [V]';
            app.PVoc = uieditfield(app.PanelSolar, 'numeric');
            app.PVoc.ValueDisplayFormat = '%.2f';
            app.VmVEditFieldLabel = uilabel(app.PanelSolar);
            app.VmVEditFieldLabel.Text = 'Vm [V]';
            app.PVm = uieditfield(app.PanelSolar, 'numeric');
            app.PVm.ValueDisplayFormat = '%.2f';
            app.IscAEditFieldLabel = uilabel(app.PanelSolar);
            app.IscAEditFieldLabel.Text = 'Isc [A]';
            app.PIsc = uieditfield(app.PanelSolar, 'numeric');
            app.PIsc.ValueDisplayFormat = '%.2f';
            app.ImAEditFieldLabel = uilabel(app.PanelSolar);
            app.ImAEditFieldLabel.Text = 'Im [A]';
            app.PIm = uieditfield(app.PanelSolar, 'numeric');
            app.PIm.ValueDisplayFormat = '%.2f';
            app.PCT = uieditfield(app.PanelSolar, 'numeric');
            app.PCT.ValueDisplayFormat = '%.2f';
            app.PCC = uieditfield(app.PanelSolar, 'numeric');
            app.PCC.ValueDisplayFormat = '%.2f';
            app.NOCTCEditFieldLabel = uilabel(app.PanelSolar);
            app.NOCTCEditFieldLabel.Text = 'NOCT [°C]';
            app.PNOCTC = uieditfield(app.PanelSolar, 'numeric');
            app.PNOCTC.ValueDisplayFormat = '%.2f';
            app.PotenciaSalidaLabel = uilabel(app.PanelSolar);
            app.PotenciaSalidaLabel.Text = 'Potencia Salida [W]';
            app.CoeficientedeTensionLabel = uilabel(app.PanelSolar);
            app.CoeficientedeTensionLabel.Text = 'Coeficiente de Tension';
            app.CoeficientePotenciaLabel = uilabel(app.PanelSolar);
            app.CoeficientePotenciaLabel.Text = 'Coeficiente Potencia';
            app.CoeficientedeCorrienteLabel = uilabel(app.PanelSolar);
            app.CoeficientedeCorrienteLabel.Text = 'Coeficiente de Corriente';
            app.TipodePanelLabel = uilabel(app.PanelSolar);
            app.TipodePanelLabel.Text = 'Tipo de Panel';
            app.TemperaturaAmbientePanel = uipanel(app.UIFigure);
            app.TemperaturaAmbientePanel.Title = 'Temperatura Ambiente';
            app.CSliderLabel = uilabel(app.TemperaturaAmbientePanel);
            app.CSliderLabel.Text = '°C';
            app.CSlider = uislider(app.TemperaturaAmbientePanel);
            app.CSlider.Limits = [-20 40];
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @CSliderValueChanged, true);
            app.TempCEditFieldLabel = uilabel(app.TemperaturaAmbientePanel);
            app.TempCEditFieldLabel.Text = 'Temp [°C]';
            app.TempLbl = uieditfield(app.TemperaturaAmbientePanel, 'numeric');
            app.TempLbl.ValueDisplayFormat = '%.1f';
            app.SeleccindeEquiposLabel = uilabel(app.UIFigure);
            app.SeleccindeEquiposLabel.Text = 'Selección de Equipos';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.InversorPanel = uipanel(app.UIFigure);
            app.InversorPanel.Title = 'Inversor';
            app.TipoInv = uidropdown(app.InversorPanel);
            app.TipoInv.Items = {'Quattro 48/5000/70-100/100 120V', 'PV3500 PRO Series 4KW', 'Growatt SPF3500ES', 'PV3000 LMPK PV30-1012 LMPK', 'PV30-1024 LMPK PV30-1024 LMPK', 'Must PV 1800-2024', 'Must PV 1800-5048', 'Must PV30-3012 LMPK', 'Axpert MKS 1K-12', 'Otro'};
            app.TipoInv.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.TipoInv.DropDownOpeningFcn = createCallbackFcn(app, @TipoInvDropDownOpening, true);
            app.TipoInv.ValueChangedFcn = createCallbackFcn(app, @TipoInvValueChanged, true);
            app.TipoInv.Value = '10';
            app.PNominalInv = uieditfield(app.InversorPanel, 'numeric');
            app.MCorrienteInv = uieditfield(app.InversorPanel, 'numeric');
            app.EficienciaInv = uieditfield(app.InversorPanel, 'numeric');
            app.TensionNomInv = uieditfield(app.InversorPanel, 'numeric');
            app.TipoInversorLabel = uilabel(app.InversorPanel);
            app.TipoInversorLabel.Text = 'Tipo Inversor';
            app.PotenciaNominalLabel = uilabel(app.InversorPanel);
            app.PotenciaNominalLabel.Text = 'Potencia Nominal  a 25°C [W]';
            app.MaxCorrienteLabel = uilabel(app.InversorPanel);
            app.MaxCorrienteLabel.Text = 'Maxima Corriente Soportada [A]';
            app.EficienciaLabel = uilabel(app.InversorPanel);
            app.EficienciaLabel.Text = 'Eficiencia [%]';
            app.TensionLabel = uilabel(app.InversorPanel);
            app.TensionLabel.Text = 'Tension Nominal [V]';
            app.BateriaPanel = uipanel(app.UIFigure);
            app.BateriaPanel.Title = 'Bateria';
            app.DiasAuto = uieditfield(app.BateriaPanel, 'numeric');
            app.ProfunDescarga = uieditfield(app.BateriaPanel, 'numeric');
            app.DiasdeAutonomiaLabel = uilabel(app.BateriaPanel);
            app.DiasdeAutonomiaLabel.Text = 'Días de Autonomia';
            app.ProfundidadBatLabel = uilabel(app.BateriaPanel);
            app.ProfundidadBatLabel.Text = 'Profundidad de Descarga [%]';
            app.TipoBateriaLabel = uilabel(app.BateriaPanel);
            app.TipoBateriaLabel.Text = 'Tipo Bateria';
            app.TipoBateria = uidropdown(app.BateriaPanel);
            app.TipoBateria.Items = {'MaxPower 200Ah GEL', 'MaxPower 150Ah GEL', 'MaxPower 100Ah GEL', 'Kaise 200Ah AGM', 'Lifepo4 200Ah Litio', 'Lifepo4 100Ah Litio', 'Lifepo4 50Ah Litio', 'Otro'};
            app.TipoBateria.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.TipoBateria.ValueChangedFcn = createCallbackFcn(app, @TipoBateriaValueChanged, true);
            app.TipoBateria.Value = '8';
            app.CapacidadBateria = uieditfield(app.BateriaPanel, 'numeric');
            app.ProfundidadBatLabel_2 = uilabel(app.BateriaPanel);
            app.ProfundidadBatLabel_2.Text = 'Capacidad de Bateria';
            app.ResultadosPanel_2 = uipanel(app.UIFigure);
            app.ResultadosPanel_2.Title = 'Resultados';
            app.BateriasPanel = uipanel(app.ResultadosPanel_2);
            app.BateriasPanel.Title = 'Baterias';
            app.GridLayout2 = uigridlayout(app.BateriasPanel);
            app.CapacidadNominalAhLabel = uilabel(app.GridLayout2);
            app.CapacidadNominalAhLabel.Text = 'Capacidad Nominal [Ah]';
            app.CapacidadCorregidaAhLabel = uilabel(app.GridLayout2);
            app.CapacidadCorregidaAhLabel.Text = 'Capacidad Corregida [Ah]';
            app.INomBatt = uilabel(app.GridLayout2);
            app.INomBatt.Text = '0';
            app.ICorrBatt = uilabel(app.GridLayout2);
            app.ICorrBatt.Text = '0';
            app.NmerodeBateriasLabel = uilabel(app.GridLayout2);
            app.NmerodeBateriasLabel.Text = 'Número de Baterias';
            app.NumBaterias = uilabel(app.GridLayout2);
            app.NumBaterias.Text = '0';
            app.Paneles = uipanel(app.ResultadosPanel_2);
            app.Paneles.Title = 'Paneles';
            app.GridLayout = uigridlayout(app.Paneles);
            app.NmerodePanelesLabel = uilabel(app.GridLayout);
            app.NmerodePanelesLabel.Text = 'Número de Paneles';
            app.NumPaneleslbl = uilabel(app.GridLayout);
            app.NumPaneleslbl.Text = '0';
            app.TemperaturaCeldasCLabel = uilabel(app.GridLayout);
            app.TemperaturaCeldasCLabel.Text = 'Temperatura Celdas [°C]';
            app.VariacindePotenciaLabel = uilabel(app.GridLayout);
            app.VariacindePotenciaLabel.Text = 'Variación de Potencia [%] ';
            app.VariacindeTensionLabel = uilabel(app.GridLayout);
            app.VariacindeTensionLabel.Text = 'Variación de Tension [%] ';
            app.VariacindeCorrienteLabel = uilabel(app.GridLayout);
            app.VariacindeCorrienteLabel.Text = 'Variación de Corriente [%] ';
            app.TemCeldLbl = uilabel(app.GridLayout);
            app.TemCeldLbl.Text = '0';
            app.VPotenciaLbl = uilabel(app.GridLayout);
            app.VPotenciaLbl.Text = '0';
            app.VTensionLbl = uilabel(app.GridLayout);
            app.VTensionLbl.Text = '0';
            app.VCorrienteLbl = uilabel(app.GridLayout);
            app.VCorrienteLbl.Text = '0';
            app.TensionmediaporpanelVLabel = uilabel(app.GridLayout);
            app.TensionmediaporpanelVLabel.Text = 'Tension media por panel [V] ';
            app.CorrientemediaporpanelALabel = uilabel(app.GridLayout);
            app.CorrientemediaporpanelALabel.Text = 'Corriente media por panel [A] ';
            app.TmediaLbl = uilabel(app.GridLayout);
            app.TmediaLbl.Text = '0';
            app.ImediaLbl = uilabel(app.GridLayout);
            app.ImediaLbl.Text = '0';
            app.TopologiaBtn = uibutton(app.UIFigure, 'push');
            app.TopologiaBtn.ButtonPushedFcn = createCallbackFcn(app, @TopologiaBtnButtonPushed, true);
            app.TopologiaBtn.Enable = 'off';
            app.TopologiaBtn.Text = 'Topologia';
            app.Ayuda = uibutton(app.UIFigure, 'push');
            app.Ayuda.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.Ayuda.Icon = fullfile(pathToMLAPP, 'Pregunta.png');
            app.Ayuda.Text = '';
            app.CostosBtn = uibutton(app.UIFigure, 'push');
            app.CostosBtn.ButtonPushedFcn = createCallbackFcn(app, @CostosBtnButtonPushed, true);
            app.CostosBtn.Enable = 'off';
            app.CostosBtn.Text = 'Costos';
            app.AyudaLabel = uilabel(app.UIFigure);
            app.AyudaLabel.Text = 'Ayuda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculos(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end