classdef Configuracion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TipodeHogarPanel               matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        DropDown_3                     matlab.ui.control.DropDown
        ModeloLabel                    matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        TipodeViviendaLabel            matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        NodePersonasLabel              matlab.ui.control.Label
        Ayuda                          matlab.ui.control.Button
        Instaladalabel_2               matlab.ui.control.Label
        EnergiaRequeridaWhLabel        matlab.ui.control.Label
        Energialbl                     matlab.ui.control.Label
        GuardarCargasButton            matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        FINButton                      matlab.ui.control.Button
        Instaladalabel                 matlab.ui.control.Label
        CargasPanel                    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        WLabel                         matlab.ui.control.Label
        WhLabel                        matlab.ui.control.Label
        PotenciaLabel                  matlab.ui.control.Label
        Pot9_2                         matlab.ui.control.NumericEditField
        Pot8_2                         matlab.ui.control.NumericEditField
        Pot7_2                         matlab.ui.control.NumericEditField
        Pot6_2                         matlab.ui.control.NumericEditField
        Pot5_2                         matlab.ui.control.NumericEditField
        Pot4_2                         matlab.ui.control.NumericEditField
        Pot3_2                         matlab.ui.control.NumericEditField
        Pot1_2                         matlab.ui.control.NumericEditField
        Pot2_2                         matlab.ui.control.NumericEditField
        Semana1                        matlab.ui.control.NumericEditField
        Semana9                        matlab.ui.control.NumericEditField
        Horas9                         matlab.ui.control.NumericEditField
        Pot9                           matlab.ui.control.NumericEditField
        Uni9                           matlab.ui.control.NumericEditField
        LicuadoraCheckBox              matlab.ui.control.CheckBox
        Semana8                        matlab.ui.control.NumericEditField
        Horas8                         matlab.ui.control.NumericEditField
        Pot8                           matlab.ui.control.NumericEditField
        Uni8                           matlab.ui.control.NumericEditField
        OtraCheckBox                   matlab.ui.control.CheckBox
        Semana7                        matlab.ui.control.NumericEditField
        Horas7                         matlab.ui.control.NumericEditField
        Pot7                           matlab.ui.control.NumericEditField
        Uni7                           matlab.ui.control.NumericEditField
        DuchaCheckBox                  matlab.ui.control.CheckBox
        Semana6                        matlab.ui.control.NumericEditField
        Horas6                         matlab.ui.control.NumericEditField
        Pot6                           matlab.ui.control.NumericEditField
        Uni6                           matlab.ui.control.NumericEditField
        ComputadorCheckBox             matlab.ui.control.CheckBox
        Semana5                        matlab.ui.control.NumericEditField
        Horas5                         matlab.ui.control.NumericEditField
        Pot5                           matlab.ui.control.NumericEditField
        Uni5                           matlab.ui.control.NumericEditField
        IluminacionCheckBox            matlab.ui.control.CheckBox
        Semana4                        matlab.ui.control.NumericEditField
        Horas4                         matlab.ui.control.NumericEditField
        Pot4                           matlab.ui.control.NumericEditField
        Uni4                           matlab.ui.control.NumericEditField
        NeveraCheckBox                 matlab.ui.control.CheckBox
        Semana3                        matlab.ui.control.NumericEditField
        Horas3                         matlab.ui.control.NumericEditField
        Pot3                           matlab.ui.control.NumericEditField
        Uni3                           matlab.ui.control.NumericEditField
        RadioCheckBox                  matlab.ui.control.CheckBox
        Semana2                        matlab.ui.control.NumericEditField
        Horas2                         matlab.ui.control.NumericEditField
        Pot2                           matlab.ui.control.NumericEditField
        Uni2                           matlab.ui.control.NumericEditField
        LavadoraCheckBox               matlab.ui.control.CheckBox
        UnidadesLabel                  matlab.ui.control.Label
        Horas1                         matlab.ui.control.NumericEditField
        Pot1                           matlab.ui.control.NumericEditField
        Uni1                           matlab.ui.control.NumericEditField
        TelevisorCheckBox              matlab.ui.control.CheckBox
        UsoSemanalesLabel              matlab.ui.control.Label
        HorasdeUsoDiarioLabel          matlab.ui.control.Label
        EnergaLabel                    matlab.ui.control.Label
        ElectrodomesticoLabel          matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        EspecificacionesdelHogarLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        calculosapp
        CallingApp;
    end

    properties (Access = public)
        Menoapp ;
    end
    methods (Access = public)
        function NotificarCierreConfiguracion(app)
            if ~isempty(app.Menoapp) && isvalid(app.Menoapp)
                notify(app.Menoapp, 'CierreConfiguracion');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Idioma)
            global Idioma2
            Idioma2=Idioma;
            if strcmp(Idioma,'English')
            app.EspecificacionesdelHogarLabel.Text='Home Specifications';
            app.ElectrodomesticoLabel.Text='Home appliances';
            app.UnidadesLabel.Text='Units';
            app.PotenciaLabel.Text='Power';
            app.EnergaLabel.Text='Energy';
            app.HorasdeUsoDiarioLabel.Text='Hours of daily use';
            app.UsoSemanalesLabel.Text='Weekly Use';
            app.LavadoraCheckBox.Text='Washer';
            app.NeveraCheckBox.Text='Fridge';
            app.LicuadoraCheckBox.Text='Blender';
            app.IluminacionCheckBox.Text='Lightning';
            app.ComputadorCheckBox.Text='Computer';
            app.TelevisorCheckBox.Text='TV';
            app.DuchaCheckBox.Text='Shower';
            app.OtraCheckBox.Text='Other'; 
            app.Instaladalabel_2.Text='Installed Potency [W]';
            app.EnergiaRequeridaWhLabel.Text='Required Energy [W/h]';
            app.GuardarCargasButton.Text='Save';
            app.FINButton.Text='Calculate';
            app.CargasPanel.Title='Loads';
            else
            app.EspecificacionesdelHogarLabel.Text='Especificaciones del Hogar';
            end
        end

        % Value changed function: LavadoraCheckBox
        function LavadoraCheckBoxValueChanged(app, event)
            value = app.LavadoraCheckBox.Value;
            if value==0
                app.Uni1.Enable='off';
                app.Pot1.Enable='off';
                app.Pot1_2.Enable='off';
                app.Horas1.Enable='off';
                app.Semana1.Enable='off';
            else
                app.Uni1.Enable='on';
                app.Pot1.Enable='on';
                app.Pot1_2.Enable='on';
                app.Horas1.Enable='on';
                app.Semana1.Enable='on';
            end
        end

        % Button pushed function: GuardarCargasButton
        function GuardarCargasButtonPushed(app, event)
            global Ptotal Etotal Idioma2
            if app.LavadoraCheckBox.Value==1
            ELav=app.Uni1.Value*app.Pot1.Value*app.Horas1.Value;
            PLav=app.Uni1.Value*app.Pot1_2.Value;
            else
            ELav=0;
            PLav=0;    
            end
            if app.NeveraCheckBox.Value==1
            ENev=app.Uni2.Value*app.Pot2.Value*app.Horas2.Value;
            PNev=app.Uni2.Value*app.Pot2_2.Value;
            else
            ENev=0;
            PNev=0;
            end
            if app.LicuadoraCheckBox.Value==1
            ELic=app.Uni3.Value*app.Pot3.Value*app.Horas3.Value;
            PLic=app.Uni3.Value*app.Pot3_2.Value;
            else
            ELic=0;
            PLic=0;
            end
            if app.RadioCheckBox.Value==1
            ERad=app.Uni4.Value*app.Pot4.Value*app.Horas4.Value;
            PRad=app.Uni4.Value*app.Pot4_2.Value;
            else
            ERad=0;
            PRad=0;
            end
            if app.IluminacionCheckBox.Value==1
            ELamp=app.Uni5.Value*app.Pot5.Value*app.Horas5.Value;
            PLamp=app.Uni5.Value*app.Pot5_2.Value;
            else
            ELamp=0;
            PLamp=0;
            end
            if app.ComputadorCheckBox.Value==1
            EComp=app.Uni6.Value*app.Pot6.Value*app.Horas6.Value;
            PComp=app.Uni6.Value*app.Pot6_2.Value;
            else
            EComp=0;
            PComp=0;
            end
            if app.TelevisorCheckBox.Value==1
            ETv=app.Uni7.Value*app.Pot7.Value*app.Horas7.Value;
            PTv=app.Uni7.Value*app.Pot7_2.Value;
            else
            ETv=0;
            PTv=0;
            end
            if app.DuchaCheckBox.Value==1
            EDuchas=app.Uni8.Value*app.Pot8.Value*app.Horas8.Value;
            PDuchas=app.Uni8.Value*app.Pot8_2.Value;
            else
            EDuchas=0;
            PDuchas=0;
            end
            if app.OtraCheckBox.Value==1
            EOtro=app.Uni9.Value*app.Pot9.Value*app.Horas9.Value;
            POtro=app.Uni9.Value*app.Pot9_2.Value;
            else
            EOtro=0;
            POtro=0;
            end
            Etotal=ELav+ENev+ELic+ERad+ELamp+EComp+ETv+EDuchas+EOtro;
            Ptotal=PLav+PNev+PLic+PRad+PLamp+PComp+PTv+PDuchas+POtro;
            app.Energialbl.Text=num2str(Etotal);
            app.Instaladalabel.Text=num2str(Ptotal);
        end

        % Value changed function: NeveraCheckBox
        function NeveraCheckBoxValueChanged(app, event)
            value = app.NeveraCheckBox.Value;
            if value==0
                app.Uni2.Enable='off';
                app.Pot2.Enable='off';
                app.Pot2_2.Enable='off';
                app.Horas2.Enable='off';
                app.Semana2.Enable='off';
            else
                app.Uni2.Enable='on';
                app.Pot2.Enable='on';
                app.Pot2_2.Enable='on';
                app.Horas2.Enable='on';
                app.Semana2.Enable='on';
            end
        end

        % Value changed function: LicuadoraCheckBox
        function LicuadoraCheckBoxValueChanged(app, event)
            value = app.LicuadoraCheckBox.Value;
            if value==0
                app.Uni3.Enable='off';
                app.Pot3.Enable='off';
                app.Pot3_2.Enable='off';
                app.Horas3.Enable='off';
                app.Semana3.Enable='off';
            else
                app.Uni3.Enable='on';
                app.Pot3.Enable='on';
                app.Pot3_2.Enable='on';
                app.Horas3.Enable='on';
                app.Semana3.Enable='on';
            end
        end

        % Value changed function: RadioCheckBox
        function RadioCheckBoxValueChanged(app, event)
            value = app.RadioCheckBox.Value;
            if value==0
                app.Uni4.Enable='off';
                app.Pot4.Enable='off';
                app.Pot4_2.Enable='off';
                app.Horas4.Enable='off';
                app.Semana4.Enable='off';
            else
                app.Uni4.Enable='on';
                app.Pot4.Enable='on';
                app.Pot4_2.Enable='on';
                app.Horas4.Enable='on';
                app.Semana4.Enable='on';
            end
        end

        % Value changed function: IluminacionCheckBox
        function IluminacionCheckBoxValueChanged(app, event)
            value = app.IluminacionCheckBox.Value;
            if value==0
                app.Uni5.Enable='off';
                app.Pot5.Enable='off';
                app.Pot5_2.Enable='off';
                app.Horas5.Enable='off';
                app.Semana5.Enable='off';
            else
                app.Uni5.Enable='on';
                app.Pot5.Enable='on';
                app.Pot5_2.Enable='on';
                app.Horas5.Enable='on';
                app.Semana5.Enable='on';
            end
        end

        % Value changed function: ComputadorCheckBox
        function ComputadorCheckBoxValueChanged(app, event)
            value = app.ComputadorCheckBox.Value;
            if value==0
                app.Uni6.Enable='off';
                app.Pot6.Enable='off';
                app.Pot6_2.Enable='off';
                app.Horas6.Enable='off';
                app.Semana6.Enable='off';
            else
                app.Uni6.Enable='on';
                app.Pot6.Enable='on';
                app.Pot6_2.Enable='on';
                app.Horas6.Enable='on';
                app.Semana6.Enable='on';
            end
        end

        % Value changed function: TelevisorCheckBox
        function TelevisorCheckBoxValueChanged(app, event)
            value = app.TelevisorCheckBox.Value;
            if value==0
                app.Uni7.Enable='off';
                app.Pot7.Enable='off';
                app.Pot7_2.Enable='off';
                app.Horas7.Enable='off';
                app.Semana7.Enable='off';
            else
                app.Uni7.Enable='on';
                app.Pot7.Enable='on';
                app.Pot7_2.Enable='on';
                app.Horas7.Enable='on';
                app.Semana7.Enable='on';
            end
        end

        % Value changed function: DuchaCheckBox
        function DuchaCheckBoxValueChanged(app, event)
            value = app.DuchaCheckBox.Value;
            if value==0
                app.Uni8.Enable='off';
                app.Pot8.Enable='off';
                app.Pot8_2.Enable='off';
                app.Horas8.Enable='off';
                app.Semana8.Enable='off';
            else
                app.Uni8.Enable='on';
                app.Pot8.Enable='on';
                app.Pot8_2.Enable='on';
                app.Horas8.Enable='on';
                app.Semana8.Enable='on';
            end
        end

        % Value changed function: OtraCheckBox
        function OtraCheckBoxValueChanged(app, event)
            value = app.OtraCheckBox.Value;
            if value==0
                app.Uni9.Enable='off';
                app.Pot9.Enable='off';
                app.Pot9_2.Enable='off';
                app.Horas9.Enable='off';
                app.Semana9.Enable='off';
            else
                app.Uni9.Enable='on';
                app.Pot9.Enable='on';
                app.Pot9_2.Enable='on';
                app.Horas9.Enable='on';
                app.Semana9.Enable='on';
            end
        end

        % Button pushed function: FINButton
        function FINButtonPushed(app, event)
             app.Menoapp.CargasButton.Enable = "on";
            global Potenciai;
            potenciaTexto = app.Instaladalabel.Text;
            Potenciai = str2double(potenciaTexto);
            global Energiai;
            energiaTexto = app.Energialbl.Text;
            Energiai=str2double(energiaTexto);
            MenoApp = findall(0, 'Name', 'Meno');
            if ~isempty(app.Menoapp) && isvalid(app.Menoapp)
                app.Menoapp.LabelHabilitado1 = true;
                delete(app.UIFigure);
            else
                disp('Meno no está abierta');
                delete(app.UIFigure);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Menoapp.CargasButton.Enable = "on";
            delete(app)
           % uialert(app.UIFigure, 'Debe guardar datos para cerrar.', 'Error', 'Icon', 'error');
        end

        % Button pushed function: Ayuda
        function AyudaButtonPushed(app, event)
            msgbox({'Seleccione el tipo de hogar que desea precargar'; '';...
                '*Seleccione el numero de personas que conforman el hogar'; ...
                '*Tipo de vivienda'; ...
                '*Modelo: Basico-Complementario';'';'Se puede modificar las cargas en el cuadro según su necesidad';''; ...
                'Una vez seleccione sus cargar y el tipo de hogar se debe guardar cargas y oprimir Calcular'});
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '2'
                   app.Uni1.Value=1;
                   app.Uni2.Value=1;
                   app.Uni3.Value=1;
                   app.Uni4.Value=1;
                   app.Uni5.Value=4;
                   app.Uni6.Value=1;
                   app.Uni7.Value=1;
                   app.Uni8.Value=1;
                case '3'
                   app.Uni1.Value=1;
                   app.Uni2.Value=1;
                   app.Uni3.Value=1;
                   app.Uni4.Value=1;
                   app.Uni5.Value=6;
                   app.Uni6.Value=2;
                   app.Uni7.Value=2;
                   app.Uni8.Value=1;
                case '4'
                   app.Uni1.Value=1;
                   app.Uni2.Value=1;
                   app.Uni3.Value=1;
                   app.Uni4.Value=1;
                   app.Uni5.Value=8;
                   app.Uni6.Value=2;
                   app.Uni7.Value=3;
                   app.Uni8.Value=1;
                case '6'
                   app.Uni1.Value=1;
                   app.Uni2.Value=1;
                   app.Uni3.Value=1;
                   app.Uni4.Value=1;
                   app.Uni5.Value=10;
                   app.Uni6.Value=3;
                   app.Uni7.Value=5;
                   app.Uni8.Value=2;
                    
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            %app.EspecificacionesdelHogarLabel.Text=value;
            switch value
                case 'Rural'
                    app.LavadoraCheckBox.Text='Motobomba';
                    app.DuchaCheckBox.Text='Ventilador';
                    app.Pot1_2.Value=250;
                    app.Pot1.Value=250;
                    app.Horas1.Value=2;
                    app.Semana1.Value=4;
                    app.Pot8_2.Value=50;
                    app.Pot8.Value=50;
                    app.Horas8.Value=5;
                    Luminarias=app.Uni5.Value-1;
                    app.Uni5.Value=Luminarias;
                case 'Urbana'
                    app.LavadoraCheckBox.Text='Lavadora';
                    app.DuchaCheckBox.Text='Ducha';
                    app.Pot1_2.Value=150;
                    app.Pot1.Value=150;
                    app.Horas1.Value=1.5;
                    app.Semana1.Value=1;
                    app.Pot8_2.Value=2500;
                    app.Pot8.Value=2500;
                    app.Horas8.Value=.2;
                    Luminarias=app.Uni5.Value+1;
                    app.Uni5.Value=Luminarias;
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case 'Basico'
                    app.LavadoraCheckBox.Value=0;
                    app.NeveraCheckBox.Value=0;
                    app.DuchaCheckBox.Value=0;
                    app.ComputadorCheckBox.Value=0;
                    app.Uni1.Enable='off';
                    app.Pot1.Enable='off';
                    app.Pot1_2.Enable='off';
                    app.Horas1.Enable='off';
                    app.Semana1.Enable='off';
                    app.Uni8.Enable='off';
                app.Pot8.Enable='off';
                app.Pot8_2.Enable='off';
                app.Horas8.Enable='off';
                app.Semana8.Enable='off';
                app.Uni6.Enable='off';
                app.Pot6.Enable='off';
                app.Pot6_2.Enable='off';
                app.Horas6.Enable='off';
                app.Semana6.Enable='off';
                app.Uni2.Enable='off';
                app.Pot2.Enable='off';
                app.Pot2_2.Enable='off';
                app.Horas2.Enable='off';
                app.Semana2.Enable='off';
                case 'Complementario'
                    app.LavadoraCheckBox.Value=1;
                    app.NeveraCheckBox.Value=1;
                    app.DuchaCheckBox.Value=1;
                    app.ComputadorCheckBox.Value=1;
                    app.Uni1.Enable='on';
                    app.Pot1.Enable='on';
                    app.Pot1_2.Enable='on';
                    app.Horas1.Enable='on';
                    app.Semana1.Enable='on';
                    app.Uni8.Enable='on';
                app.Pot8.Enable='on';
                app.Pot8_2.Enable='on';
                app.Horas8.Enable='on';
                app.Semana8.Enable='on';
                app.Uni6.Enable='on';
                app.Pot6.Enable='on';
                app.Pot6_2.Enable='on';
                app.Horas6.Enable='on';
                app.Semana6.Enable='on';
                app.Uni2.Enable='on';
                app.Pot2.Enable='on';
                app.Pot2_2.Enable='on';
                app.Horas2.Enable='on';
                app.Semana2.Enable='on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EspecificacionesdelHogarLabel = uilabel(app.UIFigure);
            app.EspecificacionesdelHogarLabel.Text = 'Especificaciones del Hogar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Escudo.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Logo IE.png';
            app.CargasPanel = uipanel(app.UIFigure);
            app.CargasPanel.Title = 'Cargas';
            app.GridLayout = uigridlayout(app.CargasPanel);
            app.ElectrodomesticoLabel = uilabel(app.GridLayout);
            app.ElectrodomesticoLabel.Text = 'Electrodomestico';
            app.EnergaLabel = uilabel(app.GridLayout);
            app.EnergaLabel.Text = 'Energía';
            app.HorasdeUsoDiarioLabel = uilabel(app.GridLayout);
            app.HorasdeUsoDiarioLabel.Text = {'Horas de Uso'; 'Diario'; ''};
            app.UsoSemanalesLabel = uilabel(app.GridLayout);
            app.UsoSemanalesLabel.Text = {'Uso'; 'Semanales'};
            app.TelevisorCheckBox = uicheckbox(app.GridLayout);
            app.TelevisorCheckBox.ValueChangedFcn = createCallbackFcn(app, @TelevisorCheckBoxValueChanged, true);
            app.TelevisorCheckBox.Text = 'Televisor';
            app.TelevisorCheckBox.Value = true;
            app.Uni1 = uieditfield(app.GridLayout, 'numeric');
            app.Pot1 = uieditfield(app.GridLayout, 'numeric');
            app.Pot1.Value = 150;
            app.Horas1 = uieditfield(app.GridLayout, 'numeric');
            app.Horas1.Value = 1.5;
            app.UnidadesLabel = uilabel(app.GridLayout);
            app.UnidadesLabel.Text = 'Unidades';
            app.LavadoraCheckBox = uicheckbox(app.GridLayout);
            app.LavadoraCheckBox.ValueChangedFcn = createCallbackFcn(app, @LavadoraCheckBoxValueChanged, true);
            app.LavadoraCheckBox.Text = 'Lavadora';
            app.LavadoraCheckBox.Value = true;
            app.Uni2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot2.Value = 28;
            app.Horas2 = uieditfield(app.GridLayout, 'numeric');
            app.Horas2.Value = 24;
            app.Semana2 = uieditfield(app.GridLayout, 'numeric');
            app.Semana2.Value = 7;
            app.RadioCheckBox = uicheckbox(app.GridLayout);
            app.RadioCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadioCheckBoxValueChanged, true);
            app.RadioCheckBox.Text = 'Radio';
            app.RadioCheckBox.Value = true;
            app.Uni3 = uieditfield(app.GridLayout, 'numeric');
            app.Pot3 = uieditfield(app.GridLayout, 'numeric');
            app.Pot3.Value = 300;
            app.Horas3 = uieditfield(app.GridLayout, 'numeric');
            app.Horas3.Value = 0.05;
            app.Semana3 = uieditfield(app.GridLayout, 'numeric');
            app.Semana3.Value = 5;
            app.NeveraCheckBox = uicheckbox(app.GridLayout);
            app.NeveraCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeveraCheckBoxValueChanged, true);
            app.NeveraCheckBox.Text = 'Nevera';
            app.NeveraCheckBox.Value = true;
            app.Uni4 = uieditfield(app.GridLayout, 'numeric');
            app.Pot4 = uieditfield(app.GridLayout, 'numeric');
            app.Pot4.Value = 5;
            app.Horas4 = uieditfield(app.GridLayout, 'numeric');
            app.Horas4.Value = 3;
            app.Semana4 = uieditfield(app.GridLayout, 'numeric');
            app.Semana4.Value = 7;
            app.IluminacionCheckBox = uicheckbox(app.GridLayout);
            app.IluminacionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IluminacionCheckBoxValueChanged, true);
            app.IluminacionCheckBox.Text = 'Iluminacion';
            app.IluminacionCheckBox.Value = true;
            app.Uni5 = uieditfield(app.GridLayout, 'numeric');
            app.Pot5 = uieditfield(app.GridLayout, 'numeric');
            app.Pot5.Value = 20;
            app.Horas5 = uieditfield(app.GridLayout, 'numeric');
            app.Horas5.Value = 6;
            app.Semana5 = uieditfield(app.GridLayout, 'numeric');
            app.Semana5.Value = 7;
            app.ComputadorCheckBox = uicheckbox(app.GridLayout);
            app.ComputadorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputadorCheckBoxValueChanged, true);
            app.ComputadorCheckBox.Text = 'Computador';
            app.ComputadorCheckBox.Value = true;
            app.Uni6 = uieditfield(app.GridLayout, 'numeric');
            app.Pot6 = uieditfield(app.GridLayout, 'numeric');
            app.Pot6.Value = 50;
            app.Horas6 = uieditfield(app.GridLayout, 'numeric');
            app.Horas6.Value = 4;
            app.Semana6 = uieditfield(app.GridLayout, 'numeric');
            app.Semana6.Value = 6;
            app.DuchaCheckBox = uicheckbox(app.GridLayout);
            app.DuchaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DuchaCheckBoxValueChanged, true);
            app.DuchaCheckBox.Text = 'Ducha';
            app.DuchaCheckBox.Value = true;
            app.Uni7 = uieditfield(app.GridLayout, 'numeric');
            app.Pot7 = uieditfield(app.GridLayout, 'numeric');
            app.Pot7.Value = 120;
            app.Horas7 = uieditfield(app.GridLayout, 'numeric');
            app.Horas7.Value = 5;
            app.Semana7 = uieditfield(app.GridLayout, 'numeric');
            app.Semana7.Value = 7;
            app.OtraCheckBox = uicheckbox(app.GridLayout);
            app.OtraCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtraCheckBoxValueChanged, true);
            app.OtraCheckBox.Text = 'Otra';
            app.OtraCheckBox.Value = true;
            app.Uni8 = uieditfield(app.GridLayout, 'numeric');
            app.Pot8 = uieditfield(app.GridLayout, 'numeric');
            app.Pot8.Value = 2500;
            app.Horas8 = uieditfield(app.GridLayout, 'numeric');
            app.Horas8.Value = 0.16;
            app.Semana8 = uieditfield(app.GridLayout, 'numeric');
            app.Semana8.Value = 7;
            app.LicuadoraCheckBox = uicheckbox(app.GridLayout);
            app.LicuadoraCheckBox.ValueChangedFcn = createCallbackFcn(app, @LicuadoraCheckBoxValueChanged, true);
            app.LicuadoraCheckBox.Text = 'Licuadora';
            app.LicuadoraCheckBox.Value = true;
            app.Uni9 = uieditfield(app.GridLayout, 'numeric');
            app.Pot9 = uieditfield(app.GridLayout, 'numeric');
            app.Horas9 = uieditfield(app.GridLayout, 'numeric');
            app.Semana9 = uieditfield(app.GridLayout, 'numeric');
            app.Semana1 = uieditfield(app.GridLayout, 'numeric');
            app.Semana1.Value = 1;
            app.Pot2_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot2_2.Value = 400;
            app.Pot1_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot1_2.Value = 150;
            app.Pot3_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot3_2.Value = 300;
            app.Pot4_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot4_2.Value = 5;
            app.Pot5_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot5_2.Value = 20;
            app.Pot6_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot6_2.Value = 50;
            app.Pot7_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot7_2.Value = 120;
            app.Pot8_2 = uieditfield(app.GridLayout, 'numeric');
            app.Pot8_2.Value = 2500;
            app.Pot9_2 = uieditfield(app.GridLayout, 'numeric');
            app.PotenciaLabel = uilabel(app.GridLayout);
            app.PotenciaLabel.Text = 'Potencia';
            app.WhLabel = uilabel(app.GridLayout);
            app.WhLabel.Text = '[Wh]';
            app.WLabel = uilabel(app.GridLayout);
            app.WLabel.Text = '[W]';
            app.Instaladalabel = uilabel(app.UIFigure);
            app.Instaladalabel.Text = '';
            app.FINButton = uibutton(app.UIFigure, 'push');
            app.FINButton.ButtonPushedFcn = createCallbackFcn(app, @FINButtonPushed, true);
            app.FINButton.Icon = 'iniciaric.png';
            app.FINButton.Text = 'Calcular';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '160426110641_promo-cropped.jpg';
            app.GuardarCargasButton = uibutton(app.UIFigure, 'push');
            app.GuardarCargasButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarCargasButtonPushed, true);
            app.GuardarCargasButton.Text = 'Guardar Cargas';
            app.Energialbl = uilabel(app.UIFigure);
            app.Energialbl.Text = '';
            app.EnergiaRequeridaWhLabel = uilabel(app.UIFigure);
            app.EnergiaRequeridaWhLabel.Text = 'Energia Requerida [Wh]';
            app.Instaladalabel_2 = uilabel(app.UIFigure);
            app.Instaladalabel_2.Text = 'Potencia Instalada [W]';
            app.Ayuda = uibutton(app.UIFigure, 'push');
            app.Ayuda.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.Ayuda.Icon = fullfile(pathToMLAPP, 'Pregunta.png');
            app.Ayuda.Text = '';
            app.TipodeHogarPanel = uipanel(app.UIFigure);
            app.TipodeHogarPanel.Title = 'Tipo de Hogar';
            app.GridLayout2 = uigridlayout(app.TipodeHogarPanel);
            app.NodePersonasLabel = uilabel(app.GridLayout2);
            app.NodePersonasLabel.Text = 'No de Personas';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'2', '3', '4', '6', '.....'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '.....';
            app.TipodeViviendaLabel = uilabel(app.GridLayout2);
            app.TipodeViviendaLabel.Text = 'Tipo de Vivienda';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'Urbana', 'Rural', '.......'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '.......';
            app.ModeloLabel = uilabel(app.GridLayout2);
            app.ModeloLabel.Text = 'Modelo';
            app.DropDown_3 = uidropdown(app.GridLayout2);
            app.DropDown_3.Items = {'Basico', 'Complementario', '.......'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '.......';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Configuracion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end