classdef Presupuesto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CostosLabel          matlab.ui.control.Label
        TopologiaBtn         matlab.ui.control.Button
        Image2               matlab.ui.control.Image
        ElpresupuestomostradoenelmomentofuetomadoLabel  matlab.ui.control.Label
        CostosPanel          matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        Total                matlab.ui.control.NumericEditField
        SubInv               matlab.ui.control.NumericEditField
        PUInv                matlab.ui.control.NumericEditField
        NInvLbl              matlab.ui.control.NumericEditField
        SubBat               matlab.ui.control.NumericEditField
        PUBat                matlab.ui.control.NumericEditField
        NBatLbl              matlab.ui.control.NumericEditField
        SubPan               matlab.ui.control.NumericEditField
        PUPan                matlab.ui.control.NumericEditField
        NPanLbl              matlab.ui.control.NumericEditField
        TotalLabel           matlab.ui.control.Label
        InversorLabel        matlab.ui.control.Label
        BateriasLabel        matlab.ui.control.Label
        PanelesLabel         matlab.ui.control.Label
        SubtotalLabel        matlab.ui.control.Label
        PrecioUnitarioLabel  matlab.ui.control.Label
        CantidadLabel        matlab.ui.control.Label
        DetalleLabel         matlab.ui.control.Label
        Image3               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Idioma, VPan, VBat, VInv, NPan, NBat)
            app.PUPan.Value=VPan;
            app.NPanLbl.Value=NPan;
            app.NBatLbl.Value=NBat;
            app.PUBat.Value=VBat;
            app.NInvLbl.Value=1;
            app.PUInv.Value=VInv;
            app.SubInv.Value=VInv;
            SubPaneles=NPan*VPan;
            app.SubPan.Value=SubPaneles;
            SubBateria=NBat*VBat;
            app.SubBat.Value=SubBateria;
            CTotal=SubBateria+SubPaneles+VInv;
            app.Total.Value=CTotal;
        end

        % Button pushed function: TopologiaBtn
        function TopologiaBtnButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Barra.png');
            app.CostosPanel = uipanel(app.UIFigure);
            app.CostosPanel.Title = 'Costos';
            app.GridLayout = uigridlayout(app.CostosPanel);
            app.DetalleLabel = uilabel(app.GridLayout);
            app.DetalleLabel.Text = 'Detalle';
            app.CantidadLabel = uilabel(app.GridLayout);
            app.CantidadLabel.Text = 'Cantidad';
            app.PrecioUnitarioLabel = uilabel(app.GridLayout);
            app.PrecioUnitarioLabel.Text = 'Precio Unitario';
            app.SubtotalLabel = uilabel(app.GridLayout);
            app.SubtotalLabel.Text = 'Subtotal';
            app.PanelesLabel = uilabel(app.GridLayout);
            app.PanelesLabel.Text = 'Paneles';
            app.BateriasLabel = uilabel(app.GridLayout);
            app.BateriasLabel.Text = 'Baterias';
            app.InversorLabel = uilabel(app.GridLayout);
            app.InversorLabel.Text = 'Inversor';
            app.TotalLabel = uilabel(app.GridLayout);
            app.TotalLabel.Text = 'Total';
            app.NPanLbl = uieditfield(app.GridLayout, 'numeric');
            app.NPanLbl.ValueDisplayFormat = '%.0f';
            app.NPanLbl.Editable = 'off';
            app.PUPan = uieditfield(app.GridLayout, 'numeric');
            app.PUPan.ValueDisplayFormat = '%.0f';
            app.PUPan.Editable = 'off';
            app.SubPan = uieditfield(app.GridLayout, 'numeric');
            app.SubPan.ValueDisplayFormat = '%.0f';
            app.SubPan.Editable = 'off';
            app.NBatLbl = uieditfield(app.GridLayout, 'numeric');
            app.NBatLbl.ValueDisplayFormat = '%.0f';
            app.NBatLbl.Editable = 'off';
            app.PUBat = uieditfield(app.GridLayout, 'numeric');
            app.PUBat.ValueDisplayFormat = '%.0f';
            app.PUBat.Editable = 'off';
            app.SubBat = uieditfield(app.GridLayout, 'numeric');
            app.SubBat.ValueDisplayFormat = '%.0f';
            app.SubBat.Editable = 'off';
            app.NInvLbl = uieditfield(app.GridLayout, 'numeric');
            app.NInvLbl.ValueDisplayFormat = '%.0f';
            app.NInvLbl.Editable = 'off';
            app.PUInv = uieditfield(app.GridLayout, 'numeric');
            app.PUInv.ValueDisplayFormat = '%.0f';
            app.PUInv.Editable = 'off';
            app.SubInv = uieditfield(app.GridLayout, 'numeric');
            app.SubInv.ValueDisplayFormat = '%.0f';
            app.SubInv.Editable = 'off';
            app.Total = uieditfield(app.GridLayout, 'numeric');
            app.Total.ValueDisplayFormat = '%.0f';
            app.Total.Editable = 'off';
            app.ElpresupuestomostradoenelmomentofuetomadoLabel = uilabel(app.UIFigure);
            app.ElpresupuestomostradoenelmomentofuetomadoLabel.Text = 'Los precios presentados en este presupuesto están basados en la fecha de creación del programa y pueden estar sujetos a variaciones según las políticas comerciales vigentes año 2024 presentados en COP(Peso Colombiano). No se contemplan costos de instalación ni operación.';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '—Pngtree—vector coins icon_3788228.png');
            app.TopologiaBtn = uibutton(app.UIFigure, 'push');
            app.TopologiaBtn.ButtonPushedFcn = createCallbackFcn(app, @TopologiaBtnButtonPushed, true);
            app.TopologiaBtn.Text = 'Volver';
            app.CostosLabel = uilabel(app.UIFigure);
            app.CostosLabel.Text = 'Costos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Presupuesto(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end