classdef Topologia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Ayuda                          matlab.ui.control.Button
        ValidarButton                  matlab.ui.control.Button
        NotaLbl                        matlab.ui.control.Label
        BateriasPanel                  matlab.ui.container.Panel
        BSLabel                        matlab.ui.control.Label
        BPLabel                        matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        VBatLabel                      matlab.ui.control.Label
        BatSerieLabel                  matlab.ui.control.Label
        TensionBateriasenserieVLabel   matlab.ui.control.Label
        NmerodeBateriasenSerieBSLabel  matlab.ui.control.Label
        IBatLabel                      matlab.ui.control.Label
        BatPalLabel                    matlab.ui.control.Label
        CorrientedeBateriasenParaleloALabel  matlab.ui.control.Label
        NmerodeParalelosBPLabel        matlab.ui.control.Label
        TopologiaLabel                 matlab.ui.control.Label
        Paneles                        matlab.ui.container.Panel
        NumPanLbl                      matlab.ui.control.Label
        NumerodePanelesUsadosLabel     matlab.ui.control.Label
        PSerieLbl                      matlab.ui.control.NumericEditField
        PParaleloLbl                   matlab.ui.control.NumericEditField
        PPLabel                        matlab.ui.control.Label
        PSLabel                        matlab.ui.control.Label
        VPanLbl                        matlab.ui.control.Label
        TensionPanelesenserieVLabel    matlab.ui.control.Label
        NmerodePanelesenSeriePSLabel   matlab.ui.control.Label
        IPanLbl                        matlab.ui.control.Label
        CorrientedePanelesenParaleloALabel  matlab.ui.control.Label
        NmerodeParalelosPPLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Idioma, NbSerie, NbParalelo, VBat, IBat, IInversor, PInversor, Imedia, Vmedia, NumPaneles)
            global IInversor1 PInversor1 Imedia1 Vmedia1 NumPan1 Idioma1;
            IInversor1=IInversor;
            PInversor1=PInversor;
            Imedia1=Imedia;
            Vmedia1=Vmedia;
            NumPan1=NumPaneles;
            Idioma1=Idioma;
            %app.TopologiaLabel.Text=num2str(NumPaneles);
            app.BatPalLabel.Text=num2str(NbParalelo);
            app.BatSerieLabel.Text=num2str(NbSerie);
            app.IBatLabel.Text=num2str(IBat);
            app.VBatLabel.Text=num2str(VBat);
            if strcmp(Idioma,'English')
                app.TopologiaLabel.Text='Topology';
                app.BateriasPanel.Title='Batteries';
                app.Paneles.Title='Panels';
                app.NmerodeParalelosPPLabel.Text='Number of parallels (PP)';
                app.CorrientedePanelesenParaleloALabel.Text='Parallel panel Current [A]';
                app.NmerodePanelesenSeriePSLabel.Text='Number of panels in series (PS)';
                app.TensionPanelesenserieVLabel.Text='Tension in panels [V]';
                app.NmerodeParalelosBPLabel.Text='Number of parallels (BP)';
                app.CorrientedeBateriasenParaleloALabel.Text='Parallel battery current [A]';
                app.NmerodeBateriasenSerieBSLabel.Text='Number of batteries in series';
                app.TensionBateriasenserieVLabel.Text='Battery voltage in would [V]';
                app.NotaLbl.Text='Note: *The number of Panels must be a minimum of the number of panels that give us the results.*The voltage must not exceed the voltage of the inverter.*The current must not exceed the current supported by the inverter.';
                app.ValidarButton.Text='Validate';
            else
            end
        end

        % Callback function
        function VolverButtonPushed(app, event)
                %
        end

        % Button pushed function: ValidarButton
        function ValidarButtonPushed(app, event)
            global IInversor1 PInversor1 Imedia1 Vmedia1 NumPan1;
            TInversor=PInversor1/IInversor1;
            TPaneles=app.PSerieLbl.Value*Vmedia1;
            IPaneles=app.PParaleloLbl.Value*Imedia1;
            NumPaneles=app.PSerieLbl.Value*app.PParaleloLbl.Value;
            if TInversor>TPaneles
                if IInversor1>IPaneles
                if NumPaneles>=NumPan1
                    msgbox('Configuración Correcta');
                else
                    warndlg('Se debe agregar mas paneles');
                end
                else
                    warndlg('Corriente de Paneles Superior a Inversor Reducir Numero de Paneles en Paralelo')
                end
            else
            warndlg('Tension de Paneles Superior a Inversor Reducir Numero de Paneles en Serie');
            end    
            app.IPanLbl.Text=num2str(IPaneles);
            app.VPanLbl.Text=num2str(TPaneles);
            
            app.NumPanLbl.Text=num2str(NumPaneles);
        end

        % Button pushed function: Ayuda
        function AyudaButtonPushed(app, event)
            global Idioma1;
            instru(Idioma1);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Paneles = uipanel(app.UIFigure);
            app.Paneles.Title = 'Paneles';
            app.Image = uiimage(app.Paneles);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Panel.png');
            app.NmerodeParalelosPPLabel = uilabel(app.Paneles);
            app.NmerodeParalelosPPLabel.Text = 'Número de Paralelos (PP) =';
            app.CorrientedePanelesenParaleloALabel = uilabel(app.Paneles);
            app.CorrientedePanelesenParaleloALabel.Text = 'Corriente de Paneles en Paralelo [A] =';
            app.IPanLbl = uilabel(app.Paneles);
            app.IPanLbl.Text = '0';
            app.NmerodePanelesenSeriePSLabel = uilabel(app.Paneles);
            app.NmerodePanelesenSeriePSLabel.Text = 'Número de Paneles en Serie (PS) =';
            app.TensionPanelesenserieVLabel = uilabel(app.Paneles);
            app.TensionPanelesenserieVLabel.Text = 'Tension Paneles en serie [V] =';
            app.VPanLbl = uilabel(app.Paneles);
            app.VPanLbl.Text = '0';
            app.PSLabel = uilabel(app.Paneles);
            app.PSLabel.Text = 'PS';
            app.PPLabel = uilabel(app.Paneles);
            app.PPLabel.Text = 'PP ';
            app.PParaleloLbl = uieditfield(app.Paneles, 'numeric');
            app.PSerieLbl = uieditfield(app.Paneles, 'numeric');
            app.NumerodePanelesUsadosLabel = uilabel(app.Paneles);
            app.NumerodePanelesUsadosLabel.Text = 'Numero de Paneles Usados =';
            app.NumPanLbl = uilabel(app.Paneles);
            app.NumPanLbl.Text = '0';
            app.TopologiaLabel = uilabel(app.UIFigure);
            app.TopologiaLabel.Text = 'Topologia';
            app.BateriasPanel = uipanel(app.UIFigure);
            app.BateriasPanel.Title = 'Baterias';
            app.NmerodeParalelosBPLabel = uilabel(app.BateriasPanel);
            app.NmerodeParalelosBPLabel.Text = 'Número de Paralelos (BP) =';
            app.CorrientedeBateriasenParaleloALabel = uilabel(app.BateriasPanel);
            app.CorrientedeBateriasenParaleloALabel.Text = 'Corriente de Baterias en Paralelo [A] =';
            app.BatPalLabel = uilabel(app.BateriasPanel);
            app.BatPalLabel.Text = '0';
            app.IBatLabel = uilabel(app.BateriasPanel);
            app.IBatLabel.Text = '0';
            app.NmerodeBateriasenSerieBSLabel = uilabel(app.BateriasPanel);
            app.NmerodeBateriasenSerieBSLabel.Text = 'Número de Baterias en Serie (BS) =';
            app.TensionBateriasenserieVLabel = uilabel(app.BateriasPanel);
            app.TensionBateriasenserieVLabel.Text = 'Tension Baterias en serie [V] =';
            app.BatSerieLabel = uilabel(app.BateriasPanel);
            app.BatSerieLabel.Text = '0';
            app.VBatLabel = uilabel(app.BateriasPanel);
            app.VBatLabel.Text = '0';
            app.Image2 = uiimage(app.BateriasPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Baterias.png');
            app.BPLabel = uilabel(app.BateriasPanel);
            app.BPLabel.Text = 'BP ';
            app.BSLabel = uilabel(app.BateriasPanel);
            app.BSLabel.Text = 'BS';
            app.NotaLbl = uilabel(app.UIFigure);
            app.NotaLbl.Text = {'Nota: '; '*El número de Paneles debe ser minimo el número de paneles que nos arrojan los resultados.'; '*La Tension no debe superar la tension del inversor.'; '*La correinte no debe superar la corriente soportada por el inversor.'};
            app.ValidarButton = uibutton(app.UIFigure, 'push');
            app.ValidarButton.ButtonPushedFcn = createCallbackFcn(app, @ValidarButtonPushed, true);
            app.ValidarButton.Text = 'Validar';
            app.Ayuda = uibutton(app.UIFigure, 'push');
            app.Ayuda.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.Ayuda.Icon = fullfile(pathToMLAPP, 'Pregunta.png');
            app.Ayuda.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Topologia(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end