classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AyudaLabel                    matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Ayuda                         matlab.ui.control.Button
        CalculoButton                 matlab.ui.control.Button
        TipodeCalculoIrradiacinButtonGroup  matlab.ui.container.ButtonGroup
        PROMEDIOButton                matlab.ui.control.RadioButton
        MINIMAButton                  matlab.ui.control.RadioButton
        kWhm2Label_3                  matlab.ui.control.Label
        InclinaciondeinstalaciondePaneles  matlab.ui.container.Panel
        Label_3                       matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        Inclinacion                   matlab.ui.control.NinetyDegreeGauge
        AnguloInclinacionEditField    matlab.ui.control.EditField
        AngulodeinclinacionLabel      matlab.ui.control.Label
        ResultadoEditField            matlab.ui.control.EditField
        RadiacionpanelinclinadoLabel  matlab.ui.control.Label
        GuardarButton                 matlab.ui.control.Button
        kWhm2Label                    matlab.ui.control.Label
        kWhm2Label_2                  matlab.ui.control.Label
        NasaBoton                     matlab.ui.control.Button
        R12EditField                  matlab.ui.control.EditField
        M12Label                      matlab.ui.control.Label
        R11EditField                  matlab.ui.control.EditField
        M11Label                      matlab.ui.control.Label
        R10EditField                  matlab.ui.control.EditField
        M10Label                      matlab.ui.control.Label
        R9EditField                   matlab.ui.control.EditField
        M9Label                       matlab.ui.control.Label
        R8EditField                   matlab.ui.control.EditField
        M8Label                       matlab.ui.control.Label
        R7EditField                   matlab.ui.control.EditField
        R7Label                       matlab.ui.control.Label
        R6EditField                   matlab.ui.control.EditField
        M6EditFieldLabel              matlab.ui.control.Label
        R5EditField                   matlab.ui.control.EditField
        M5EditFieldLabel              matlab.ui.control.Label
        R3EditField                   matlab.ui.control.EditField
        M3EditFieldLabel              matlab.ui.control.Label
        R4EditField                   matlab.ui.control.EditField
        M4EditFieldLabel              matlab.ui.control.Label
        R2EditField                   matlab.ui.control.EditField
        R2EditFieldLabel              matlab.ui.control.Label
        R1EditField                   matlab.ui.control.EditField
        R1EditFieldLabel              matlab.ui.control.Label
        MESESButtonGroup              matlab.ui.container.ButtonGroup
        MESButton_12                  matlab.ui.control.RadioButton
        MESButton_11                  matlab.ui.control.RadioButton
        MESButton_10                  matlab.ui.control.RadioButton
        MESButton_9                   matlab.ui.control.RadioButton
        MESButton_8                   matlab.ui.control.RadioButton
        MESButton_7                   matlab.ui.control.RadioButton
        MESButton_6                   matlab.ui.control.RadioButton
        MESButton_5                   matlab.ui.control.RadioButton
        MESButton_4                   matlab.ui.control.RadioButton
        MESButton_3                   matlab.ui.control.RadioButton
        MESButton_2                   matlab.ui.control.RadioButton
        MESButton                     matlab.ui.control.RadioButton
        RadiacionPromedioEditField    matlab.ui.control.EditField
        RadiacionPromedioEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp;
        Menoapp;
        configuracionapp;
        datosapp;
        datos;
        bar1;
        texto1;
        nombreRadiacionSolar = '';
        nombrePromedio = '';
        nombreRadiacionPanelInclinado = '';
    end
    properties (Access = public)
        MenoApp;
        RadiacionPromedio
    end
    methods (Access = public)
        function NotificarCierre(app)
            notify(app, 'CierreApp2');
        end
    end

    methods (Access = private)
        function ActualizarIdioma(app, idiomaSeleccionado)
            global IdiomaSeleccionado;
            IdiomaSeleccionado = idiomaSeleccionado;
            if strcmp(idiomaSeleccionado, 'Español')
                app.CalculoButton.Text = 'CALCULAR';
                app.NasaBoton.Text = 'Cargar Datos';
                app.MESESButtonGroup.Title = 'MESES';
                app.UIAxes.YLabel.String = 'Radiacion Solar';
                app.UIAxes.XLabel.String = 'Meses';
                app.UIAxes.Title.String = 'Radiacion Solar por Mes';
                app.nombreRadiacionSolar = 'Radiación Solar';
                app.nombrePromedio = 'Promedio';
                app.nombreRadiacionPanelInclinado = 'Radiación en Panel Inclinado';
                app.TipodeCalculoIrradiacinButtonGroup.Title = 'RADIACIÓN';
                app.PROMEDIOButton.Text = 'PROMEDIO';
                app.MINIMAButton.Text= 'MINIMO';
            elseif strcmp(idiomaSeleccionado, 'English')
                app.MINIMAButton.Text= 'MINIMUM';
                app.PROMEDIOButton.Text = 'AVERAGE';
                app.TipodeCalculoIrradiacinButtonGroup.Title = 'RADIATION';
                app.NasaBoton.Text = 'Nasa Data';
                app.MESESButtonGroup.Title = 'MONTHS';
                app.MESButton.Text = '1 month';
                app.MESButton_2.Text = '2 month';
                app.MESButton_3.Text = '3 month';
                app.MESButton_4.Text = '4 month';
                app.MESButton_5.Text = '5 month';
                app.MESButton_6.Text = '6 month';
                app.MESButton_7.Text = '7 month';
                app.MESButton_8.Text = '8 month';
                app.MESButton_9.Text = '9 month';
                app.MESButton_10.Text = '10 month';
                app.MESButton_11.Text = '11 month';
                app.MESButton_12.Text = '12 month';
                app.CalculoButton.Text = 'CALCULATE';
                app.GuardarButton.Text = 'Save';
                app.AngulodeinclinacionLabel.Text = 'Inclination Angle';
                app.RadiacionpanelinclinadoLabel.Text= 'Inclined panel radiation';
                app.RadiacionPromedioEditFieldLabel.Text= 'Average Radiation';
                app.UIAxes.Title.String = 'Solar Radiation per Month';
                app.UIAxes.YLabel.String = 'Solar Radiation';
                app.UIAxes.XLabel.String = 'Month';
                app.nombreRadiacionSolar = 'Solar Radiation';
                app.nombrePromedio = 'Average';
                app.nombreRadiacionPanelInclinado = 'Radiation in Inclined Panel';
                app.InclinaciondeinstalaciondePaneles.Title = 'Tilting panels';
            else
                app.Language = 'es';

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, menoapp, idiomaSeleccionado)
            app.GuardarButton.Visible = 'off';
            app.Menoapp = menoapp;
            archivoCargado = menoapp.datos;
            app.UITable.Data = archivoCargado;
            app.R1EditField.Enable = 'off';
            app.R2EditField.Enable = 'off';
            app.R3EditField.Enable = 'off';
            app.R4EditField.Enable = 'off';
            app.R5EditField.Enable = 'off';
            app.R6EditField.Enable = 'off';
            app.R7EditField.Enable = 'off';
            app.R8EditField.Enable = 'off';
            app.R9EditField.Enable = 'off';
            app.R10EditField.Enable = 'off';
            app.R11EditField.Enable = 'off';
            app.R12EditField.Enable = 'off';
            invisibleButton = uiradiobutton(app.MESESButtonGroup);
            invisibleButton.Visible = 'off';
            app.MESESButtonGroup.SelectedObject = invisibleButton;
            app.ActualizarIdioma(idiomaSeleccionado);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)

            if app.MESButton.Value == true && app.CheckBox.Value == 1
                app.R1EditField.Value = string(datos_app.Properties.VariableNames(1, indices(1, 2)));
            end
            if app.MESButton_2.Value == true && app.CheckBox_2.Value == 1
                app.R2EditField.Value = string(datos_app.Properties.VariableNames(1, indices(1, 2)));
            end
            if app.MESButton_3.Value == true && app.CheckBox_3.Value == 1
                app.R2EditField.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            if app.MESButton_4.Value == true && app.CheckBox_4.Value == 1
                app.R4EditField.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            if app.MESButton_5.Value == true && app.CheckBox_4.Value == 1
                app.R5EditField.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            if app.MESButton_6.Value == true && app.CheckBox_4.Value == 1
                app.R6EditField.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
        end

        % Button pushed function: CalculoButton
        function CalculoButtonPushed(app, event)
            valoresRadiacion = [
                str2double(app.R1EditField.Value), str2double(app.R2EditField.Value), ...
                str2double(app.R3EditField.Value), str2double(app.R4EditField.Value), ...
                str2double(app.R5EditField.Value), str2double(app.R6EditField.Value), ...
                str2double(app.R7EditField.Value), str2double(app.R8EditField.Value), ...
                str2double(app.R9EditField.Value), str2double(app.R10EditField.Value), ...
                str2double(app.R11EditField.Value), str2double(app.R12EditField.Value)
                ];
            valoresRadiacion(isnan(valoresRadiacion)) = [];
            if ~isempty(valoresRadiacion)
                app.GuardarButton.Visible = 'on';
            else
                app.GuardarButton.Visible = 'off';
            end
            global anguloInclinacionGrados;
            if app.PROMEDIOButton.Value
                promedioRadiacion = mean(valoresRadiacion);
                resultado = promedioRadiacion;
                nombreResultado = app.nombrePromedio;
            elseif app.MINIMAButton.Value
                resultado = min(valoresRadiacion);
                nombreResultado = 'Mínimo';
            else
                resultado = min(valoresRadiacion);
                nombreResultado = 'Mínimo';
            end
            app.RadiacionPromedioEditField.Value = num2str(resultado);
            meses = 1:12;
            radiacionPorMes = valoresRadiacion;
            plot(app.UIAxes, meses, radiacionPorMes, 'bo-', 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            y = resultado * ones(size(meses));
            plot(app.UIAxes, meses, y, 'r--', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, app.nombreRadiacionSolar, [nombreResultado ' (' num2str(resultado) ')']);
            xticklabels(app.UIAxes, strcat(string(meses), 'M'));
            anguloInclinacionRadianes = deg2rad(anguloInclinacionGrados);
            radiacionGlobal = resultado;
            radiacionDirecta = 0.4 * radiacionGlobal;
            radiacionDifusa = 0.6 * radiacionGlobal;
            radiacionEnPanelInclinado = (radiacionDirecta * cos(anguloInclinacionRadianes)) + radiacionDifusa;
            app.ResultadoEditField.Value = num2str(radiacionEnPanelInclinado);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, meses, radiacionEnPanelInclinado * ones(size(meses)), 'g--', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, app.nombreRadiacionSolar, [nombreResultado ' (' num2str(resultado) ')'], app.nombreRadiacionPanelInclinado);
            xticklabels(app.UIAxes, strcat(string(meses), 'M'));

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uialert(app.UIFigure, 'Oprima el boton Guardar.', 'Error', 'Icon', 'error');
        end

        % Selection changed function: MESESButtonGroup
        function MESESButtonPushed(app, event)
            seleccion = app.MESESButtonGroup.SelectedObject;
            cuadrosTexto = [app.R1EditField, app.R2EditField, app.R3EditField, app.R4EditField, app.R5EditField, app.R6EditField, app.R7EditField, app.R8EditField, app.R9EditField, app.R10EditField, app.R11EditField, app.R12EditField];
            for i = 1:length(cuadrosTexto)
                cuadrosTexto(i).Enable = 'off';
            end
            if seleccion == app.MESButton
                cuadrosTexto(1).Enable = 'on';
                valorCelda = app.UITable.Data{18, 2};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R1EditField.Value = valorCelda; %
            elseif seleccion == app.MESButton_2
                cuadrosTexto(2).Enable = 'on';
                valorCelda = app.UITable.Data{18, 3};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R2EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_3
                cuadrosTexto(3).Enable = 'on';
                valorCelda = app.UITable.Data{18, 4};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R3EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_4
                cuadrosTexto(4).Enable = 'on';
                valorCelda = app.UITable.Data{18, 5};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R4EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_5
                cuadrosTexto(5).Enable = 'on';
                valorCelda = app.UITable.Data{18, 6};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R5EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_6
                cuadrosTexto(6).Enable = 'on';
                valorCelda = app.UITable.Data{18, 7};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R6EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_7
                cuadrosTexto(7).Enable = 'on';
                valorCelda = app.UITable.Data{18, 8};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R7EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_8
                cuadrosTexto(8).Enable = 'on';
                valorCelda = app.UITable.Data{18, 9};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R8EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_9
                cuadrosTexto(9).Enable = 'on';
                valorCelda = app.UITable.Data{18, 10};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R9EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_10
                cuadrosTexto(10).Enable = 'on';
                valorCelda = app.UITable.Data{18, 11};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R10EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_11
                cuadrosTexto(11).Enable = 'on';
                valorCelda = app.UITable.Data{18, 12};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R11EditField.Value = valorCelda;
            elseif seleccion == app.MESButton_12
                cuadrosTexto(12).Enable = 'on';
                valorCelda = app.UITable.Data{18, 13};
                if isnumeric(valorCelda)
                    valorCelda = num2str(valorCelda);
                elseif ~ischar(valorCelda)
                    valorCelda = char(valorCelda);
                end
                app.R12EditField.Value = valorCelda;
            end
        end

        % Button pushed function: NasaBoton
        function NasaBotonButtonPushed(app, event)
            valoresCelda = app.UITable.Data{18, 2:end};
            valoresCelda = cellfun(@num2str, valoresCelda, 'UniformOutput', false);
            camposTexto = [app.R1EditField, app.R2EditField, app.R3EditField, app.R4EditField, app.R5EditField, app.R6EditField, app.R7EditField, app.R8EditField, app.R9EditField, app.R10EditField, app.R11EditField, app.R12EditField];
            for i = 1:numel(camposTexto)
                if i <= numel(valoresCelda)
                    camposTexto(i).Enable = 'on';
                    camposTexto(i).Value = valoresCelda{i};
                else
                    camposTexto(i).Enable = 'off';
                    camposTexto(i).Value = '';
                end
            end
            app.R1EditField.Enable = 'off';
            app.R2EditField.Enable = 'off';
            app.R3EditField.Enable = 'off';
            app.R4EditField.Enable = 'off';
            app.R5EditField.Enable = 'off';
            app.R6EditField.Enable = 'off';
            app.R7EditField.Enable = 'off';
            app.R8EditField.Enable = 'off';
            app.R9EditField.Enable = 'off';
            app.R10EditField.Enable = 'off';
            app.R11EditField.Enable = 'off';
            app.R12EditField.Enable = 'off';
            radiacionSolarPorMes = [
                str2double(app.R1EditField.Value), str2double(app.R2EditField.Value), ...
                str2double(app.R3EditField.Value), str2double(app.R4EditField.Value), ...
                str2double(app.R5EditField.Value), str2double(app.R6EditField.Value), ...
                str2double(app.R7EditField.Value), str2double(app.R8EditField.Value), ...
                str2double(app.R9EditField.Value), str2double(app.R10EditField.Value), ...
                str2double(app.R11EditField.Value), str2double(app.R12EditField.Value)
                ];
            plot(app.UIAxes, 1:12, radiacionSolarPorMes, 'o-', 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'b', 'LineWidth', 2);
            meses = cell(1, 12);
            for i = 1:12
                meses{i} = [num2str(i) ];
            end
            xticks(app.UIAxes, 1:12);
            xticklabels(app.UIAxes, strcat(string(meses), 'M'));
            app.Menoapp = [];
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
             app.Menoapp.RadiacionButton.Enable = "on";
            global RadiacionPromedioGlobal;
            RadiacionPromedioGlobal=app.ResultadoEditField.Value  ;
            menoApp = findall(0, 'Name', 'Meno');
            if ~isempty(menoApp)
                menoApp.LabelHabilitado = true;
                delete(app.UIFigure);
            else
                delete(app.UIFigure);
            end
        end

        % Callback function
        function GraficarButtonPushed(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global anguloInclinacionGrados promedioRadiacion;
            anguloInclinacionGrados = app.Slider.Value;
            app.Inclinacion.Value=anguloInclinacionGrados;
            app.AnguloInclinacionEditField.Value=num2str(anguloInclinacionGrados);
        end

        % Callback function
        function ButtonPushed(app, event)
            rotar=imrotate(app.Image,45);
            imshow(rotar,'Parent');
        end

        % Value changed function: AnguloInclinacionEditField
        function AnguloInclinacionEditFieldValueChanged(app, event)
            value = app.AnguloInclinacionEditField.Value;

        end

        % Button pushed function: Ayuda
        function AyudaButtonPushed(app, event)
            msgbox({'Se debe cargar las variables cargadas en el software en "Cargar Datos"';''; ...
                'Una vez se carguen los datos podremos visualizarlos en la grafica';''; ...
                'Con los datos cargados se puede realizar modificaciones a los valores tomados mes a mes para correcciones'; '';...
                'Se debe seleccionar el tipo de calculo a realizar, se recomienda hacer calculos con el metodo minimo nivel de irradiación'; '';...
                'Siguiendo el proceso se modifica el ángulo a la cual el panel sera instalado, según necesidad del usuario'; '';...
                'Se procede a calcular y guardar para continuar con el proceso.'});

            %global IdiomaSeleccionado;
            %instru(IdiomaSeleccionado); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'column 6'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RadiacionPromedioEditFieldLabel = uilabel(app.UIFigure);
            app.RadiacionPromedioEditFieldLabel.Visible = 'off';
            app.RadiacionPromedioEditFieldLabel.Text = 'Radiacion Promedio';
            app.RadiacionPromedioEditField = uieditfield(app.UIFigure, 'text');
            app.MESESButtonGroup = uibuttongroup(app.UIFigure);
            app.MESESButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MESESButtonPushed, true);
            app.MESESButtonGroup.Title = 'MESES';
            app.MESButton = uiradiobutton(app.MESESButtonGroup);
            app.MESButton.Text = '1 MES';
            app.MESButton.Value = true;
            app.MESButton_2 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_2.Text = '2 MES';
            app.MESButton_3 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_3.Text = '3 MES';
            app.MESButton_4 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_4.Text = '4 MES';
            app.MESButton_5 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_5.Text = '5 MES';
            app.MESButton_6 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_6.Text = '6 MES';
            app.MESButton_7 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_7.Text = '7 MES';
            app.MESButton_8 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_8.Text = '8 MES';
            app.MESButton_9 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_9.Text = '9 MES';
            app.MESButton_10 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_10.Text = '10 MES';
            app.MESButton_11 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_11.Text = '11 MES';
            app.MESButton_12 = uiradiobutton(app.MESESButtonGroup);
            app.MESButton_12.Text = '12 MES';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'M1';
            app.R1EditField = uieditfield(app.UIFigure, 'text');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'M2';
            app.R2EditField = uieditfield(app.UIFigure, 'text');
            app.M4EditFieldLabel = uilabel(app.UIFigure);
            app.M4EditFieldLabel.Text = 'M4';
            app.R4EditField = uieditfield(app.UIFigure, 'text');
            app.M3EditFieldLabel = uilabel(app.UIFigure);
            app.M3EditFieldLabel.Text = 'M3';
            app.R3EditField = uieditfield(app.UIFigure, 'text');
            app.M5EditFieldLabel = uilabel(app.UIFigure);
            app.M5EditFieldLabel.Text = 'M5';
            app.R5EditField = uieditfield(app.UIFigure, 'text');
            app.M6EditFieldLabel = uilabel(app.UIFigure);
            app.M6EditFieldLabel.Text = 'M6';
            app.R6EditField = uieditfield(app.UIFigure, 'text');
            app.R7Label = uilabel(app.UIFigure);
            app.R7Label.Text = 'M7';
            app.R7EditField = uieditfield(app.UIFigure, 'text');
            app.M8Label = uilabel(app.UIFigure);
            app.M8Label.Text = 'M8';
            app.R8EditField = uieditfield(app.UIFigure, 'text');
            app.M9Label = uilabel(app.UIFigure);
            app.M9Label.Text = 'M9';
            app.R9EditField = uieditfield(app.UIFigure, 'text');
            app.M10Label = uilabel(app.UIFigure);
            app.M10Label.Text = {'M10'; ''};
            app.R10EditField = uieditfield(app.UIFigure, 'text');
            app.M11Label = uilabel(app.UIFigure);
            app.M11Label.Text = 'M11';
            app.R11EditField = uieditfield(app.UIFigure, 'text');
            app.M12Label = uilabel(app.UIFigure);
            app.M12Label.Text = {'M12'; ''};
            app.R12EditField = uieditfield(app.UIFigure, 'text');
            app.NasaBoton = uibutton(app.UIFigure, 'push');
            app.NasaBoton.ButtonPushedFcn = createCallbackFcn(app, @NasaBotonButtonPushed, true);
            app.NasaBoton.Text = 'Cargar Datos';
            app.kWhm2Label_2 = uilabel(app.UIFigure);
            app.kWhm2Label_2.Text = 'kWh/m2';
            app.kWhm2Label = uilabel(app.UIFigure);
            app.kWhm2Label.Text = 'kWh/m2';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.RadiacionpanelinclinadoLabel = uilabel(app.UIFigure);
            app.RadiacionpanelinclinadoLabel.Text = 'Irradiación con panel inclinado';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'text');
            app.ResultadoEditField.Editable = 'off';
            app.InclinaciondeinstalaciondePaneles = uipanel(app.UIFigure);
            app.InclinaciondeinstalaciondePaneles.Title = 'Inclinacion de instalacion de Paneles';
            app.AngulodeinclinacionLabel = uilabel(app.InclinaciondeinstalaciondePaneles);
            app.AngulodeinclinacionLabel.Text = 'Angulo de inclinacion';
            app.AnguloInclinacionEditField = uieditfield(app.InclinaciondeinstalaciondePaneles, 'text');
            app.AnguloInclinacionEditField.ValueChangedFcn = createCallbackFcn(app, @AnguloInclinacionEditFieldValueChanged, true);
            app.AnguloInclinacionEditField.Editable = 'off';
            app.AnguloInclinacionEditField.Tooltip = {''};
            app.AnguloInclinacionEditField.Value = '0';
            app.Inclinacion = uigauge(app.InclinaciondeinstalaciondePaneles, 'ninetydegree');
            app.Inclinacion.Limits = [0 45];
            app.Slider = uislider(app.InclinaciondeinstalaciondePaneles);
            app.Slider.Limits = [0 45];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_3 = uilabel(app.InclinaciondeinstalaciondePaneles);
            app.Label_3.Text = '3';
            app.kWhm2Label_3 = uilabel(app.UIFigure);
            app.kWhm2Label_3.Text = 'kWh/m2';
            app.TipodeCalculoIrradiacinButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodeCalculoIrradiacinButtonGroup.Title = 'Tipo de Calculo Irradiación';
            app.MINIMAButton = uiradiobutton(app.TipodeCalculoIrradiacinButtonGroup);
            app.MINIMAButton.Text = 'MINIMA';
            app.MINIMAButton.Value = true;
            app.PROMEDIOButton = uiradiobutton(app.TipodeCalculoIrradiacinButtonGroup);
            app.PROMEDIOButton.Text = 'PROMEDIO';
            app.CalculoButton = uibutton(app.UIFigure, 'push');
            app.CalculoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculoButtonPushed, true);
            app.CalculoButton.Icon = 'iniciaric.png';
            app.CalculoButton.Text = 'Calcular';
            app.Ayuda = uibutton(app.UIFigure, 'push');
            app.Ayuda.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.Ayuda.Icon = fullfile(pathToMLAPP, 'Pregunta.png');
            app.Ayuda.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.AyudaLabel = uilabel(app.UIFigure);
            app.AyudaLabel.Text = 'Ayuda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end